classdef IMU_data_logger_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EulerAnglesRollPitchYawTextAreaLabel  matlab.ui.control.Label
        EulerAnglesRollPitchYawTextArea  matlab.ui.control.TextArea
        CalibrationStatusLampLabel  matlab.ui.control.Label
        CalibrationStatusLamp       matlab.ui.control.Lamp
        LogTextAreaLabel            matlab.ui.control.Label
        LogTextArea                 matlab.ui.control.TextArea
        ManualEntryEditFieldLabel   matlab.ui.control.Label
        ManualEntryEditField        matlab.ui.control.EditField
        GoButton                    matlab.ui.control.Button
        RecordDataButton            matlab.ui.control.Button
        IMUCalibrationandDataLoggerLabel  matlab.ui.control.Label
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        InstantDataPanel            matlab.ui.container.Panel
        C_MLabel                    matlab.ui.control.Label
        C_ALabel                    matlab.ui.control.Label
        C_GLabel                    matlab.ui.control.Label
        QuaternionLabel             matlab.ui.control.Label
        C_SLabel                    matlab.ui.control.Label
        TimeStampLabel              matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ard % arduino object
        DataTransferring (1,1) logical = false;
        stopLogging (1,1) logical = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.stopLogging = false;
            i = 0;

            if ~app.DataTransferring
                app.LogTextArea.Value = "Getting Data....";
                app.EulerAnglesRollPitchYawTextArea.Value = "Calculating...";

                % Get the figure ready
                app.UIAxes.XLim = [-1.5 1.5];
                app.UIAxes.YLim = [-1.5 1.5];
                app.UIAxes.ZLim = [-1.5 1.5];
                hold(app.UIAxes,'on') 
                grid(app.UIAxes,'on')
                xlabel(app.UIAxes,'X-East')
                ylabel(app.UIAxes,'Y-North')
                zlabel(app.UIAxes,'Z-Up')
                quiver3(app.UIAxes,0,0,0,1.5,0,0,'b-','LineWidth',2,'DisplayName','East (X)');
                quiver3(app.UIAxes,0,0,0,0,1.5,0,'r-','LineWidth',2,'DisplayName','North (Y)');
                quiver3(app.UIAxes,0,0,0,0,0,1.5,'g-','LineWidth',2,'DisplayName','Gravity (Z)');

                rot_x_XData = [0 1]; rot_x_YData = [0 0]; rot_x_ZData = [0 0];
                rot_x = plot3(app.UIAxes,rot_x_XData, rot_x_YData, rot_x_ZData, 'b--');
                rot_x.XDataSource = 'rot_x_XData'; rot_x.YDataSource = 'rot_x_YData'; rot_x.ZDataSource = 'rot_x_ZData';

                rot_y_XData = [0 0]; rot_y_YData = [0 1]; rot_y_ZData = [0 0];
                rot_y = plot3(app.UIAxes,rot_y_XData, rot_y_YData, rot_y_ZData, 'r--');
                rot_y.XDataSource = 'rot_y_XData';
                rot_y.YDataSource = 'rot_y_YData';
                rot_y.ZDataSource = 'rot_y_ZData';
                
                rot_z_XData = [0 0]; rot_z_YData = [0 0]; rot_z_ZData = [0 1];
                rot_z = plot3(app.UIAxes,rot_z_XData, rot_z_YData, rot_z_ZData, 'g--');
                rot_z.XDataSource = 'rot_z_XData';
                rot_z.YDataSource = 'rot_z_YData';
                rot_z.ZDataSource = 'rot_z_ZData';
                view(app.UIAxes,45,45)
                
                app.DataTransferring = true;
                app.ard = serialport("COM9",115200);
                configureTerminator(app.ard,"LF");
                %app.ard.UserData = struct("Data",[],"Count",1);
            end
            flush(app.ard);

            app.LogTextArea.Value = "";
            %viewer = HelperOrientationViewer('Title',{'Test Orientation'});

            while ~app.stopLogging
                flush(app.ard)
                timestamp = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss.SSS');
                data = readline(app.ard);
                data_line = string(timestamp) + '-->' + string(data);
                app.LogTextArea.Value{end+1} = char(data_line);
                app.TimeStampLabel.Text = string(timestamp);
                drawnow

                CQ = str2num(replace(string(data),"],[","] ["));
                
                if length(CQ) > 6 
                    if sum(CQ(1,1:4)) == 12
                        app.CalibrationStatusLamp.Color = "Green";
                    else
                        app.CalibrationStatusLamp.Color = "Red";
                    end
                    
                    app.C_SLabel.Text = string(CQ(1));
                    app.C_GLabel.Text = string(CQ(2));
                    app.C_ALabel.Text = string(CQ(3));
                    app.C_MLabel.Text = string(CQ(4));
                    Q = CQ(5:8);
                    app.QuaternionLabel.Text = num2str(Q);
                    
                    R = rotmat(quaternion(Q),'frame')*eye(3);
                    rot_x_XData = [0 R(1,1)];
                    rot_x_YData = [0 R(1,2)];
                    rot_x_ZData = [0 R(1,3)];
                    
                    rot_y_XData = [0 R(2,1)];
                    rot_y_YData = [0 R(2,2)];
                    rot_y_ZData = [0 R(2,3)];
                
                    rot_z_XData = [0 R(3,1)];
                    rot_z_YData = [0 R(3,2)];
                    rot_z_ZData = [0 R(3,3)];
                    rot_x.XData = rot_x_XData; rot_x.YData = rot_x_YData; rot_x.ZData = rot_x_ZData;
                    rot_y.XData = rot_y_XData; rot_y.YData = rot_y_YData; rot_y.ZData = rot_y_ZData;
                    rot_z.XData = rot_z_XData; rot_z.YData = rot_z_YData; rot_z.ZData = rot_z_ZData;

                    w = CQ(5); x = CQ(6); y = CQ(7); z = CQ(8);
                    if x^2+y^2+z^2+w^2 ~= 0
                        e_x = round( atan2d(2*(x*y+z*w),(x^2-y^2-z^2+w^2)), 2); %form for Roll
                        e_y = round( asind(-2*(x*z-y*w)/(x^2+y^2+z^2+w^2)), 2); %form for Pitch
                        e_z = round( atan2d(2*(y*z+x*w),(-x^2-y^2+z^2+w^2)), 2); %form for Yaw/heading
                    else
                        e_x = 0; e_y = 0; e_z = 0;
                    end
                    
                    try
                        app.EulerAnglesRollPitchYawTextArea.Value{end+1} = char(string(e_x)+','+string(e_y)+','+string(e_z));                    
                    catch
                        app.EulerAnglesRollPitchYawTextArea.Value{end+1} = warning("error");
                    end
                end
                drawnow
                scroll(app.LogTextArea, 'bottom')
                scroll(app.EulerAnglesRollPitchYawTextArea,'bottom')
                i = i+1;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopLogging = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Orientation')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IMU Orientation.png';
            app.InstantDataPanel = uipanel(app.UIFigure);
            app.InstantDataPanel.Title = 'Instant Data';
            app.TimeStampLabel = uilabel(app.InstantDataPanel);
            app.TimeStampLabel.Tag = 'TimeStamp';
            app.TimeStampLabel.Text = 'TimeStamp';
            app.C_SLabel = uilabel(app.InstantDataPanel);
            app.C_SLabel.Tag = 'c';
            app.C_SLabel.Text = 'C_S';
            app.QuaternionLabel = uilabel(app.InstantDataPanel);
            app.QuaternionLabel.Tag = 'Quaternion';
            app.QuaternionLabel.Text = 'Quaternion';
            app.C_GLabel = uilabel(app.InstantDataPanel);
            app.C_GLabel.Tag = 'C_G';
            app.C_GLabel.Text = 'C_G';
            app.C_ALabel = uilabel(app.InstantDataPanel);
            app.C_ALabel.Tag = 'C_A';
            app.C_ALabel.Text = 'C_A';
            app.C_MLabel = uilabel(app.InstantDataPanel);
            app.C_MLabel.Tag = 'C_M';
            app.C_MLabel.Text = 'C_M';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.IMUCalibrationandDataLoggerLabel = uilabel(app.UIFigure);
            app.IMUCalibrationandDataLoggerLabel.Text = 'IMU Calibration and Data Logger';
            app.RecordDataButton = uibutton(app.UIFigure, 'push');
            app.RecordDataButton.Text = 'Record Data';
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.Text = 'Go';
            app.ManualEntryEditField = uieditfield(app.UIFigure, 'text');
            app.ManualEntryEditFieldLabel = uilabel(app.UIFigure);
            app.ManualEntryEditFieldLabel.Text = 'Manual Entry';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.LogTextArea.Editable = 'off';
            app.LogTextArea.Placeholder = 'Serial Monitor';
            app.LogTextAreaLabel = uilabel(app.UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.CalibrationStatusLamp = uilamp(app.UIFigure);
            app.CalibrationStatusLampLabel = uilabel(app.UIFigure);
            app.CalibrationStatusLampLabel.Text = 'Calibration Status';
            app.EulerAnglesRollPitchYawTextArea = uitextarea(app.UIFigure);
            app.EulerAnglesRollPitchYawTextArea.Placeholder = 'Not Yet Ready';
            app.EulerAnglesRollPitchYawTextAreaLabel = uilabel(app.UIFigure);
            app.EulerAnglesRollPitchYawTextAreaLabel.Text = 'Euler Angles {Roll,Pitch,Yaw}';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IMU_data_logger_UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end